classdef UI_autoreflow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        SelectDatasetButton           matlab.ui.control.Button
        VisualizationtypeButtonGroup  matlab.ui.container.ButtonGroup
        TimelapseButton               matlab.ui.control.RadioButton
        DifferenceImageButton         matlab.ui.control.RadioButton
        SliderButton                  matlab.ui.control.RadioButton
        ColordistributionButton       matlab.ui.control.RadioButton
        ModeofChangeDropDownLabel     matlab.ui.control.Label
        ModeofChangeDropDown          matlab.ui.control.DropDown
        CurrentpathLabel              matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        CurrentpathTextArea           matlab.ui.control.TextArea
        AlignmentmethodButtonGroup    matlab.ui.container.ButtonGroup
        FasterKAZEButton              matlab.ui.control.ToggleButton
        MoreRobustSURFButton          matlab.ui.control.ToggleButton
        RightPanel                    matlab.ui.container.Panel
        AnalyzeButton                 matlab.ui.control.Button
        StatusField                   matlab.ui.control.EditField
        Panel                         matlab.ui.container.Panel
        FPSLabel                      matlab.ui.control.Label
        FPSField                      matlab.ui.control.NumericEditField
        EndPlaybackButton             matlab.ui.control.StateButton
        ImageAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        viz_type % which type of visualization to produce
        mode_change % which mode of visualization chosen in Drop down
        analyze_ok
    end
    
    properties (Access = public)
        target_folder % source folder for images
        EndPlayback
        surf_flag
    end
    
    methods (Access = private)
        
        function update_image(app,target_folder,imageFiles,i)
            
            %imageFiles=dir([target_folder '/*.jpg']);
            imageFileName = imageFiles(i).name;
            
            
            fullFileName = fullfile(target_folder, imageFileName);
            
            try
                im = imread(fullFileName);
                
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end
            imagesc(app.ImageAxes,im);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ImageAxes.Visible = 'on';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');

            app.viz_type='Timelapse';
            app.mode_change='Quick';
            
            app.target_folder='.';
            app.CurrentpathTextArea.Value='Please select a dataset';
            
            app.AnalyzeButton.BackgroundColor=[1.00,0.00,0.00];
            app.analyze_ok=false;
            
            app.StatusField.Value='Load Dataset';
            
            app.FPSField.Value=1;

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {709, 709};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {337, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function: CurrentpathTextArea, ImageAxes, 
        % SelectDatasetButton
        function LoadDatasetsButtonPushed(app, event)
            
            % Display uigetfile dialog
            
            app.target_folder = uigetdir('Load a dataset ');
            
            % Make sure user didn't cancel uigetfile dialog
            if ~isfolder(app.target_folder)
                errorMessage = sprintf('Error: The target folder does not exist:\n%s', app.target_folder);
                uiwait(warndlg(errorMessage));
                return;
            end
            
            imageFiles=dir([app.target_folder '/*.jpg']);
            
            if size(imageFiles,1)==0
                errorMessage = sprintf('Error: The target folder does not contain any images. Please choose another!', app.target_folder);
                uiwait(warndlg(errorMessage));
                return;
            end
            
            app.CurrentpathTextArea.Value=app.target_folder;
            
            app.analyze_ok=true;
            app.AnalyzeButton.BackgroundColor=[0,1.00,0.00];
            app.StatusField.Value='Ready!';
            
            
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            if app.analyze_ok
                
                %update status field
                app.StatusField.Value='Processing...';
                app.StatusField.Visible=true;
                drawnow

%                 %Load images from source folder
                 imageFiles=dir([app.target_folder '/*.jpg']); % all images
%                 Nfile=size(imageFiles,1);                     % Number of images
                
                
                switch app.viz_type
                    case 'Timelapse'
                        %create a timelapse visualization
                         
                        timelapse(app);
                    case 'Difference Image'
                        %create difference image visualization
                        diff_image(app)
                        
                        
                    case 'Slider'
                        image_left=imread(fullfile(app.target_folder,imageFiles(1).name));
                        image_right=imread(fullfile(app.target_folder,imageFiles(end).name));
                        [tform, ~]=register(rgb2gray(image_left),rgb2gray(image_right),0);
                        image_right = imwarp(image_right,tform,'OutputView',imref2d(size(image_left)));
                        
                        
                        slider_func(app, app.ImageAxes, image_right, image_left)
                        
                    case 'Color distribution?'
                        
                end
                app.StatusField.Value='Done!';
            end
            
        end

        % Selection changed function: VisualizationtypeButtonGroup
        function VisualizationtypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.VisualizationtypeButtonGroup.SelectedObject;
            app.viz_type=selectedButton.Text;
            
            %show timelapse playback controls only if the option is
            %selected
            if strcmp(app.viz_type,'Timelapse')
                app.Panel.Visible=true;
            else
                app.Panel.Visible=false;
            end
        
        end

        % Value changed function: ModeofChangeDropDown
        function ModeofChangeDropDownValueChanged(app, event)
            value = app.ModeofChangeDropDown.Value;
            app.mode_change = value;
        end

        % Callback function
        function PlayPauseButtonValueChanged(app, event)
            app.EndPlayback = app.EndPlaybackButton.Value;
         
        end

        % Selection changed function: AlignmentmethodButtonGroup
        function AlignmentmethodButtonGroupSelectionChanged(app, event)
            selectedButton = app.AlignmentmethodButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Faster (KAZE)')
                app.surf_flag=0;
                app.AlignmentmethodButtonGroup.BackgroundColor=[0,1,1];
            elseif strcmp(selectedButton.Text,'More Robust (SURF)')
                app.surf_flag=1;
                app.AlignmentmethodButtonGroup.BackgroundColor=[0,1,0];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SelectDatasetButton = uibutton(app.LeftPanel, 'push');
            app.SelectDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDatasetsButtonPushed, true);
            app.SelectDatasetButton.Text = 'Select Dataset';
            app.VisualizationtypeButtonGroup = uibuttongroup(app.LeftPanel);
            app.VisualizationtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VisualizationtypeButtonGroupSelectionChanged, true);
            app.VisualizationtypeButtonGroup.Title = 'Visualization type';
            app.TimelapseButton = uiradiobutton(app.VisualizationtypeButtonGroup);
            app.TimelapseButton.Text = 'Timelapse';
            app.TimelapseButton.Value = true;
            app.DifferenceImageButton = uiradiobutton(app.VisualizationtypeButtonGroup);
            app.DifferenceImageButton.Text = 'Difference Image';
            app.SliderButton = uiradiobutton(app.VisualizationtypeButtonGroup);
            app.SliderButton.Text = 'Slider';
            app.ColordistributionButton = uiradiobutton(app.VisualizationtypeButtonGroup);
            app.ColordistributionButton.Text = 'Color distribution?';
            app.ModeofChangeDropDownLabel = uilabel(app.LeftPanel);
            app.ModeofChangeDropDownLabel.Text = 'Mode of Change';
            app.ModeofChangeDropDown = uidropdown(app.LeftPanel);
            app.ModeofChangeDropDown.Items = {'Quick', 'Slow', 'Little', 'Large'};
            app.ModeofChangeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeofChangeDropDownValueChanged, true);
            app.ModeofChangeDropDown.Value = 'Quick';
            app.CurrentpathLabel = uilabel(app.LeftPanel);
            app.CurrentpathLabel.Text = 'Current path:';
            app.TextArea = uitextarea(app.LeftPanel);
            app.TextArea.Value = {'Hi there! '; 'This is just a quick info for you! First, you have to browse a scene with the "Select Dataset" button. '; 'Second you can choose the visualization mode and type to your preference. '; 'Now, you can start the app with the "Analyze" button. With the LOOP button, you can choose to play the videos in an infinite loop. ENJOY :)'};
            app.CurrentpathTextArea = uitextarea(app.LeftPanel);
            app.CurrentpathTextArea.ValueChangedFcn = createCallbackFcn(app, @LoadDatasetsButtonPushed, true);
            app.CurrentpathTextArea.Editable = 'off';
            app.AlignmentmethodButtonGroup = uibuttongroup(app.LeftPanel);
            app.AlignmentmethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AlignmentmethodButtonGroupSelectionChanged, true);
            app.AlignmentmethodButtonGroup.Title = 'Alignment method';
            app.FasterKAZEButton = uitogglebutton(app.AlignmentmethodButtonGroup);
            app.FasterKAZEButton.Text = 'Faster (KAZE)';
            app.FasterKAZEButton.Value = true;
            app.MoreRobustSURFButton = uitogglebutton(app.AlignmentmethodButtonGroup);
            app.MoreRobustSURFButton.Text = 'More Robust (SURF)';
            app.RightPanel = uipanel(app.GridLayout);
            app.AnalyzeButton = uibutton(app.RightPanel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.StatusField = uieditfield(app.RightPanel, 'text');
            app.Panel = uipanel(app.RightPanel);
            app.FPSLabel = uilabel(app.Panel);
            app.FPSLabel.Text = 'FPS:';
            app.FPSField = uieditfield(app.Panel, 'numeric');
            app.FPSField.Limits = [1 20];
            app.FPSField.Value = 1;
            app.EndPlaybackButton = uibutton(app.Panel, 'state');
            app.EndPlaybackButton.Text = 'End Playback';
            app.ImageAxes = uiaxes(app.RightPanel);
            title(app.ImageAxes, 'Output')
            app.ImageAxes.XTick = [];
            app.ImageAxes.YTick = [];
            app.ImageAxes.ButtonDownFcn = createCallbackFcn(app, @LoadDatasetsButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI_autoreflow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end